classdef EstrousMismatchGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        CloseWindowButton        matlab.ui.control.Button
        PreviousButton           matlab.ui.control.Button
        AcceptChangesButton      matlab.ui.control.Button
        NextButton               matlab.ui.control.Button
        Panel_3                  matlab.ui.container.Panel
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        Panel_4                  matlab.ui.container.Panel
        ButtonGroup              matlab.ui.container.ButtonGroup
        Button                   matlab.ui.control.RadioButton
        Button_2                 matlab.ui.control.RadioButton
        EditField_2              matlab.ui.control.EditField
        EditField_1              matlab.ui.control.EditField
        ClassificationLabel      matlab.ui.control.Label
        Labelsucessfullychanged  matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        EditField                matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
        Panel_5                  matlab.ui.container.Panel
        WARNINGLabel             matlab.ui.control.Label
        ClickheretoeditmismatchesButton_2  matlab.ui.control.Button
        ClickheretoacceptnetpredictionsButton_2  matlab.ui.control.Button
    end

    
    properties (Access = private)
        classifier % EstrousNet Classifier
        curr_idx % current image
        mismatch_idx % index of all mismatched images
        curr_mismatch_idx % current mismatch index
        label_changed_idx % indices of labels that are changed
        cytology_plot
        net_probability_plot
        cyclicity_plot
    end
    
    methods (Access = public)
        function plotMismatchResults(app)
            app.Labelsucessfullychanged.Visible = 'off'; % turn off stage choice when plotting next image
            app.Button.Value = true; % origianlly set net to true
            app.Button_2.Value = false;
            curr_netLabel = app.classifier.netLabels(app.curr_mismatch_idx);
            curr_cycleLabel = app.classifier.cyclicityLabels(app.curr_mismatch_idx);
            app.EditField_1.Value = char(curr_netLabel); % set button values
            app.EditField_2.Value = char(curr_cycleLabel);
            
            filenames = app.classifier.rawImages.Files;
            curr_pathname = filenames{app.curr_mismatch_idx};
            [~,fname,ext] = fileparts(curr_pathname);
            curr_fname = strcat(fname,ext);
            set(app.EditField, 'Value', curr_fname) % display current filename under image
            
            %% Plot EstrousNet classification results
            I = readimage(app.classifier.rawImages,app.curr_mismatch_idx); % reduce size of image for quicker plotting
            app.cytology_plot.CData = imresize(I,0.1);
            Ypred = app.classifier.finalLabels(app.curr_mismatch_idx);
            title(app.UIAxes,{'\fontsize{15}\color{white}Net Prediction: '...
                '\fontsize{14}\color{white}' + string(Ypred) + ", " + num2str(100*max(app.classifier.labelProbabilities(app.curr_mismatch_idx,:)),3) + "%"});
            
            app.net_probability_plot.YData = app.classifier.labelProbabilities(app.curr_mismatch_idx,:);
            
            %% Plot linear regression classification results
            num_cycle_labels = regexprep(cellstr(app.classifier.cyclicityLabels),{'diestrus','proestrus','metestrus','estrus'},...
                {'1','2','4','3'}); % convert categorical stages from cycle to numerical indices
            num_cycle_labels = str2num(cell2mat(num_cycle_labels)); % convert to double
            
            app.cyclicity_plot.XData = app.curr_mismatch_idx;
            app.cyclicity_plot.YData = num_cycle_labels(app.curr_mismatch_idx); % just change current marker
            
            app.update();
        end
    end
    
    methods (Access = private)
        
        function initialize2(app)
            app.Labelsucessfullychanged.Visible = 'off'; % turn off stage choice when plotting next image
            app.Button.Value = true; % origianlly set net to true
            app.Button_2.Value = false;
            curr_netLabel = app.classifier.netLabels(app.curr_mismatch_idx);
            curr_cycleLabel = app.classifier.cyclicityLabels(app.curr_mismatch_idx);
            app.EditField_1.Value = char(curr_netLabel); % set button values
            app.EditField_2.Value = char(curr_cycleLabel);
            
            filenames = app.classifier.rawImages.Files;
            curr_pathname = filenames{app.curr_mismatch_idx};
            [~,fname,ext] = fileparts(curr_pathname);
            curr_fname = strcat(fname,ext);
            set(app.EditField, 'Value', curr_fname) % display current filename under image
            
            %% Plot EstrousNet classification results
            I = readimage(app.classifier.rawImages,app.curr_mismatch_idx);
            I = imresize(I,0.1); % reduce size of image for quicker plotting
            app.cytology_plot = imagesc(I,'Parent',app.UIAxes); % display image before processing
            axis(app.UIAxes, 'image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            Ypred = app.classifier.finalLabels(app.curr_mismatch_idx);
            title(app.UIAxes,{'\fontsize{15}\color{white}Net Prediction: '...
                '\fontsize{14}\color{white}' + string(Ypred) + ", " + num2str(100*max(app.classifier.labelProbabilities(app.curr_mismatch_idx,:)),3) + "%"});
            
            classNames = categories(app.classifier.finalLabels); % all lables included in classification
            if length(classNames) == 4
                classIdx = [1;4;2;3];
            else
                classIdx = [1;3;2];
            end
            classNames = classNames(classIdx); % switch 2nd and last dims
            scoreTop = app.classifier.labelProbabilities(app.curr_mismatch_idx,:);
            app.net_probability_plot = barh(app.UIAxes2,scoreTop,'FaceColor','#f78278');
            title(app.UIAxes2,'\fontsize{14}\color{white}Stage Probability Predictions');
            xlabel(app.UIAxes2,'\fontsize{14}\color{white}Probability')
            xlim(app.UIAxes2,[0 1])
            yticklabels(app.UIAxes2,strcat('\fontsize{13}\color{white}',classNames))
            
            
            %% Plot linear regression classification results
            num_net_labels = regexprep(cellstr(app.classifier.finalLabels),{'diestrus','proestrus','metestrus','estrus'},...
                {'1','2','4','3'}); % convert categorical stages from net to numerical indices
            num_net_labels = str2num(cell2mat(num_net_labels)); % convert to double
            num_cycle_labels = regexprep(cellstr(app.classifier.cyclicityLabels),{'diestrus','proestrus','metestrus','estrus'},...
                {'1','2','4','3'}); % convert categorical stages from cycle to numerical indices
            num_cycle_labels = str2num(cell2mat(num_cycle_labels)); % convert to double
            x = [1:length(filenames)]';
            plot(app.UIAxes3,num_cycle_labels,'LineWidth',5,'Color','#f78278');
            hold(app.UIAxes3,'on')
            scatter(app.UIAxes3,x,num_net_labels,'filled','MarkerEdgeColor',[0.24 0.58 0.71],'MarkerFaceColor',[0.24 0.58 0.71]);
            hold(app.UIAxes3,'on')
            app.cyclicity_plot = plot(app.UIAxes3,app.curr_mismatch_idx,num_cycle_labels(app.curr_mismatch_idx),'r*','LineWidth',11); %% emphasize current point
            hold(app.UIAxes3,'off')
            title(app.UIAxes3,'\fontsize{14}\color{white}Stage Fitting Predictions')
            xlabel(app.UIAxes3,'\fontsize{14}\color{white}Test Image')
            set(app.UIAxes3,'XTick',1:1:length(x)) % show all x ticks
            xlim(app.UIAxes3,[1 length(filenames)])
            yticks(app.UIAxes3, 1:length(classNames))
            yticklabels(app.UIAxes3,strcat('\fontsize{13}\color{white}',classNames))
            ylim(app.UIAxes3, [0.5 length(classNames)+0.5]) % give y axis 0.5 buffer
            legend(app.UIAxes3,'cycle prediction','net prediction','current image','Position',[0.17 0.35 0.07 0.05])
            
            drawnow();
            figure(app.UIFigure)
        end
        
        function update(app)
            if app.curr_mismatch_idx == app.mismatch_idx(end) % Next button disabled when you're at last image
                app.NextButton.Enable = 'off';
            else
                app.NextButton.Enable = 'on'; % enabled otherwise
            end
            if app.curr_mismatch_idx == app.mismatch_idx(1)
                app.PreviousButton.Enable = 'off'; % Prev button disabled when you're at 1st image
            else
                app.PreviousButton.Enable = 'on';  % enabled otherwise
            end
            drawnow();
            figure(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classifier, mismatch_idx)
            app.classifier = classifier;
            app.mismatch_idx = mismatch_idx; % import variables
            app.curr_idx = 1; % set to 1 initially
            app.curr_mismatch_idx = app.mismatch_idx(1); % set to first mismatched image
            app.PreviousButton.Enable = 'off'; % Previous button begins off
            app.ClassificationLabel.Visible = 'on';
            app.Labelsucessfullychanged.Visible = 'off'; % Label changed panel invisible to start
            plotMismatchResults(app); % plot results
            app.label_changed_idx = zeros(length(app.classifier.netLabels),1);
            app.initialize2();
            app.update();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.curr_idx = app.curr_idx - 1;
            app.curr_mismatch_idx = app.mismatch_idx(app.curr_idx);
            app.plotMismatchResults();
            app.update();
            app.curr_idx = max(app.curr_idx, 1); % prevent curr_idx from passing bounds
        end

        % Button pushed function: AcceptChangesButton
        function AcceptChangesButtonPushed(app, event)
            EstrousNetPlotting(app.classifier,1,app.label_changed_idx);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.curr_idx = app.curr_idx + 1;
            app.curr_mismatch_idx = app.mismatch_idx(app.curr_idx);
            app.plotMismatchResults();
            app.update();
            app.curr_idx = min(app.curr_idx, length(app.classifier.netLabels)); % prevent curr_idx from passing bounds
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key; % enable keyboard functionality for next and prev buttons
            switch key
                case 'rightarrow'
                    NextButtonPushed(app, event);
                case 'leftarrow'
                    PreviousButtonPushed(app, event);
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.Button_2.Value % if user selects cyclicity classification
                curr_cycleLabel = app.classifier.cyclicityLabels(app.curr_mismatch_idx);
                app.Labelsucessfullychanged.Visible = 'on'; % make "Label changed" panel visible
                title(app.UIAxes,{'\fontsize{15}\color{white}Cyclicity Prediction: '...
                    '\fontsize{14}\color{white}' + string(curr_cycleLabel) + ', selected by user'}) % replot title when changed
                drawnow();
                app.classifier.finalLabels(app.curr_mismatch_idx) = curr_cycleLabel; % change output label to cyclicity label
                app.label_changed_idx(app.curr_mismatch_idx) = 1; % mark where label has been changed
            else
                app.classifier.finalLabels(app.curr_mismatch_idx) = app.classifier.netLabels(app.curr_mismatch_idx); % go back to net classification
                app.Labelsucessfullychanged.Visible = 'off';
            end
        end

        % Button pushed function: ClickheretoeditmismatchesButton_2
        function ClickheretoeditmismatchesButtonPushed(app, event)
            app.Panel_5.Visible = 'off';
        end

        % Button pushed function: 
        % ClickheretoacceptnetpredictionsButton_2
        function ClickheretoacceptnetpredictionsButtonPushed(app, event)
            EstrousNetPlotting(app.classifier,1,app.label_changed_idx);
        end

        % Button pushed function: CloseWindowButton
        function CloseWindowButtonPushed(app, event)
            close(app.UIFigure) % close figure
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Panel = uipanel(app.UIFigure);
            app.CloseWindowButton = uibutton(app.Panel, 'push');
            app.CloseWindowButton.ButtonPushedFcn = createCallbackFcn(app, @CloseWindowButtonPushed, true);
            app.CloseWindowButton.Text = 'Close Window';
            app.PreviousButton = uibutton(app.Panel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.AcceptChangesButton = uibutton(app.Panel, 'push');
            app.AcceptChangesButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptChangesButtonPushed, true);
            app.AcceptChangesButton.Text = 'Accept Changes';
            app.NextButton = uibutton(app.Panel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Panel_3 = uipanel(app.Panel);
            app.UIAxes2 = uiaxes(app.Panel_3);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Panel_3);
            zlabel(app.UIAxes3, 'Z')
            app.Panel_4 = uipanel(app.Panel);
            app.ButtonGroup = uibuttongroup(app.Panel_4);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '';
            app.EditField_2 = uieditfield(app.ButtonGroup, 'text');
            app.EditField_1 = uieditfield(app.ButtonGroup, 'text');
            app.ClassificationLabel = uilabel(app.Panel_4);
            app.ClassificationLabel.Text = 'Which classification would you like to accept:';
            app.Labelsucessfullychanged = uilabel(app.Panel);
            app.Labelsucessfullychanged.Text = 'Label sucessfully changed.';
            app.Panel_2 = uipanel(app.Panel);
            app.EditField = uieditfield(app.Panel_2, 'text');
            app.UIAxes = uiaxes(app.Panel_2);
            zlabel(app.UIAxes, 'Z')
            app.Panel_5 = uipanel(app.Panel);
            app.WARNINGLabel = uilabel(app.Panel_5);
            app.WARNINGLabel.Text = 'WARNING: Some of your stage classifications do not follow a typical cycle.';
            app.ClickheretoeditmismatchesButton_2 = uibutton(app.Panel_5, 'push');
            app.ClickheretoeditmismatchesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClickheretoeditmismatchesButtonPushed, true);
            app.ClickheretoeditmismatchesButton_2.Text = 'Click here to edit mismatches';
            app.ClickheretoacceptnetpredictionsButton_2 = uibutton(app.Panel_5, 'push');
            app.ClickheretoacceptnetpredictionsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClickheretoacceptnetpredictionsButtonPushed, true);
            app.ClickheretoacceptnetpredictionsButton_2.Text = 'Click here to accept net predictions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EstrousMismatchGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end